classdef GraficasCantidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        IniciarmovimientoButton  matlab.ui.control.Button
        GridLayout3              matlab.ui.container.GridLayout
        ResultadosButton         matlab.ui.control.Button
        GridLayout4              matlab.ui.container.GridLayout
        GridLayout5              matlab.ui.container.GridLayout
        Label                    matlab.ui.control.Label
        Contador                 matlab.ui.control.NumericEditField
        MovimientoLabel          matlab.ui.control.Label
        GridLayout6              matlab.ui.container.GridLayout
        TiemposLabel             matlab.ui.control.Label
        TiempoTotal              matlab.ui.control.NumericEditField
        FCodo                    matlab.ui.control.UIAxes
        AMuneca                  matlab.ui.control.UIAxes
        FMuneca                  matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tiempo; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
       app.Contador.Value = 0;
     app.tiempo=1;
          
           while(app.tiempo) 
            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);

        
            % value = app.TiempoTotal.Value - duration;
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'c')
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'c');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'c')
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'c')
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'c')
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'c')
   

    maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
    i=1;
    
    while i<maxsize
        if i<length(yHFF)
            plot(app.FHombro,xHFF(1:i),yHFF(1:i))
        end
        if i<length(yHAF)
            plot(app.AHombro,xHAF(1:i),yHAF(1:i))
        end
        if i<length(yCFF)
            plot(app.FCodo,xCFF(1:i),yCFF(1:i))
        end
        if i<length(yCSF)
            plot(app.SCodo,xCSF(1:i),yCSF(1:i))
        end
        if i<length(yMFF)
            plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
        end
        if i<length(yMAF)
            plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
        end
        i=i+30;
        drawnow
        pause(0.01)
    end
     value = app.TiempoTotal.Value;
         
                value1 = value - duration;
                app.TiempoTotal.Value = value1;

                movimiento = app.Contador.Value;
                movimiento1 = movimiento + 1;
                app.Contador.Value = movimiento1;
    
                if (value1 <= 0)
                  app.TiempoTotal.Value = 0;
                  app.Contador.Value = movimiento;
                  
                   %Escribir en un excel
                T = table (movimiento);
                L = {'Movimiento'};
                C = table2cell(T);
                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                xlswrite('ProgresoCantidad.xlsx',A,'xlswrite','A2');
        break;

    end
           end


%  value = app.TiempoTotal.Value - duration;
        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end

        % Value changed function: TiempoTotal
        function TiempoTotalValueChanged(app, event)
           
            value = app.TiempoTotal.Value;
            
           % while (app.tiempo)
             %   value1 = value - duration;
             %   app.TiempoTotal.Value = value1;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '';
            app.Contador = uieditfield(app.GridLayout5, 'numeric');
            app.MovimientoLabel = uilabel(app.GridLayout5);
            app.MovimientoLabel.Text = 'Movimiento:';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.TiemposLabel = uilabel(app.GridLayout6);
            app.TiemposLabel.Text = {'Tiempo (s):'; ''};
            app.TiempoTotal = uieditfield(app.GridLayout6, 'numeric');
            app.TiempoTotal.ValueChangedFcn = createCallbackFcn(app, @TiempoTotalValueChanged, true);
            app.TiempoTotal.Value = 30;
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCantidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end