classdef GraficasTiempo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        Transcurrido             matlab.ui.control.Label
        TiempoLabel              matlab.ui.control.Label
        IniciarmovimientoButton  matlab.ui.control.Button
        GridLayout2              matlab.ui.container.GridLayout
        SimulacinRobotButton     matlab.ui.control.Button
        ResultadosButton         matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        FCodo                    matlab.ui.control.UIAxes
        SCodo                    matlab.ui.control.UIAxes
        AHombro                  matlab.ui.control.UIAxes
        FHombro                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        pintar = 0; % Description
        contador = 0;
       tiempo ; % Description
          t1 ;
          tiempotranscurrido;
    end

    methods (Access = private)

        function readData(app,src,~)
            data = readline(src);
            data=split(data);
            data =str2double(data);

            src.UserData.Data(size(src.UserData.Data,1)+1,:)= data;

            src.UserData.Count = src.UserData.Count + 1;

            if src.UserData.Count > 100001
                configureCallback(src,"off");
                plot(app.scr.UserData.Data(2:end));
                %     plot(app.SCodo.scr.UserData.Data(2:end));
                %     plot(app.AHombro.scr.UserData.Data(2:end));
                %     plot(app.FHombro.scr.UserData.Data(2:end));
                %     plot(app.AMuneca.scr.UserData.Data(2:end));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
            app.pintar = 1;
            app.tiempo = 1;


            %tiempo de duracion del movimiento
            maxim= 4;
            minim= 1;
            duration = minim+(maxim-minim)*rand(1);

            [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'t')
            [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'t');
            [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'t')
            [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'t')
            [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'t')
            [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1, duration,'t')

 if app.tiempo == 1


app.t1 = tic;

   end

            puerto = serialport("COM9",115200);
            configureTerminator(puerto,"LF",4);
            puerto.UserData = struct('Data',[],'Count',1);
            readline(puerto)
            write(puerto, 's','string')
            for i = 1:40
                readline(puerto);
            end
            flush(puerto);
            puerto.UserData.Data=[];
            muestras = 4000;
            x1m = 1:5 :muestras;

            i = 1;
             while(app.pintar==1)
                readData(app,puerto);
                x1 = puerto.UserData.Data(:,1);
                figure(1), plot(x1m(1:i),x1(1:i),'r');
                drawnow;
                %  x = puerto.UserData.Data(:,1);
                %    figure(2),  plot(x1(1:i),x(1:i),'r');
                % drawnow;
                %
                % x = puerto.UserData.Data(:,1);
                %   figure(3),  plot(x2(1:i),x(1:i),'r');
                % drawnow;

                 y1 = puerto.UserData.Data(:,2);
                % figure(4), plot(x1(1:i),y(1:i),'g');
                % drawnow;

                % y = puerto.UserData.Data(:,2);
                % figure(5), plot(x1(1:i),y(1:i),'g');
                % drawnow;
                %
                 x2 = puerto.UserData.Data(:,3);
                % figure(6), plot(x1(1:i),z(1:i),'b');
                % drawnow;
                 y2 = puerto.UserData.Data(:,4);
                i=i+1;
            end
            %x = puerto.UserData.Data(:,1);

            comprobar = 1;
            if (comprobar ~= 0)
                app.contador = app.contador +1;
            end
            if (app.pintar == 0)
                filename = 'PruebasTiempo.xlsx';

                xlswrite(filename,x1,app.contador,'A')
                xlswrite(filename,y1,app.contador,'B')
                xlswrite(filename,x2,app.contador,'C')
                 xlswrite(filename,y2,app.contador,'D')


                [num,txt,raw] =    xlsread(filename,app.contador)
                plot(app.FHombro,num(:,3))
                plot(app.AHombro,num(:,4))
                plot(app.FCodo,num(:,2)) %SEGUNDO SENSOR
                plot(app.SCodo,num(:,1)) %SEGUNDO SENSOR


            end

            %Escribir en un excel

            ID = app.contador;
            LabelCalidad = {'Tiempo'};
            LabelTitulos = {'Metrica','Movimiento','ID','Tiempo','Rango','Máximo', 'Mínimo'};
 
            xlswrite('DatosInformeCantidad2.xlsx',LabelTitulos,app.contador,'A1');
            xlswrite('DatosInformeCantidad2.xlsx',LabelCalidad,app.contador,'A2:A5');

            %MOVIMIENTOS 
            FlexHombro = {'Flexión de Hombro'};
            AbdHombro = {'Abducción de Hombro'};
            FlexCodo = {'Flexión de Hombro'};
            SupCodo = {'Supinación de Codo'};
            xlswrite('DatosInformeTiempo2.xlsx',FlexHombro,app.contador,'B2');
            xlswrite('DatosInformeTiempo2.xlsx',AbdHombro,app.contador,'B3');
            xlswrite('DatosInformeTiempo2.xlsx',FlexCodo,app.contador,'B4');
            xlswrite('DatosInformeTiempo2.xlsx',SupCodo,app.contador,'B5');

            xlswrite('DatosInformeTiempo2.xlsx',ID,app.contador,'C2:C5');
            
            TiempoInforme = app.tiempotranscurrido;
            xlswrite('DatosInformeTiempo2.xlsx',TiempoInforme,app.contador,'D2:D5');

            %MAXIMOS
            maximoHF = max (x2);
            maximoHA = max(y2);
            maximoCS = max(x1); %Segundo sensor
            maximoCF = max(y1); %Segunado sensor

            xlswrite('DatosInformeTiempo2.xlsx',maximoHF,app.contador,'F2');
            xlswrite('DatosInformeTiempo2.xlsx',maximoHA,app.contador,'F3');
            xlswrite('DatosInformeTiempo2.xlsx',maximoCF,app.contador,'F4');
            xlswrite('DatosInformeTiempo2.xlsx',maximoCS,app.contador,'F5');

            %MINIMOS
            minimoHF = min(x2);
            minimoHA = min(y2);
            minimoCS = min(x1);
            minimoCF = min(y1);

            xlswrite('DatosInformeTiempo2.xlsx',minimoHF,app.contador,'G2');
            xlswrite('DatosInformeTiempo2.xlsx',minimoHA,app.contador,'G3');
            xlswrite('DatosInformeTiempo2.xlsx',minimoCS,app.contador,'G4');
            xlswrite('DatosInformeTiempo2.xlsx',minimoCF,app.contador,'G5');

            %RANGOS
            rangoHF = maximoHF - minimoHF;
            rangoHA = maximoHA - minimoHA;
            rangoCS = maximoCS - minimoCS;
            rangoCF = maximoCF - minimoCF;

            xlswrite('DatosInformeTiempo2.xlsx',rangoHF,app.contador,'E2');
            xlswrite('DatosInformeTiempo2.xlsx',rangoHA,app.contador,'E3');
            xlswrite('DatosInformeTiempo2.xlsx',rangoCS,app.contador,'E4');
            xlswrite('DatosInformeTiempo2.xlsx',rangoCF,app.contador,'E5');


        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Tiempo;
            closereq ();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           close
         app.tiempotranscurrido = toc(app.t1);
     value =  string(app.tiempotranscurrido) ;
    set(app.Transcurrido, 'Text', value);

app.tiempo=0;
            app.pintar = 0;
            %             x = puerto.UserData.Data(:,1);
            %
            %             plot(app.FHombro,x(:),'r');
        end

        % Button pushed function: SimulacinRobotButton
        function SimulacinRobotButtonPushed(app, event)
             filename = 'PruebasTiempo.xlsx';
               
a = arduino('COM8');
sHombro =servo(a,'A0'); 
sCodo = servo(a,'A1');
datos = xlsread(filename,app.contador);
datosFlexionHombro = datos(:,3);
datosFlexionCodo = datos(:,2);


datosFlexionHombro = datosFlexionHombro*1/180;
datosFlexionCodo = datosFlexionCodo*1/180;


for i=1 : length(datosFlexionHombro)

        writePosition(sHombro,datosFlexionHombro(i,1))
        writePosition(sCodo,datosFlexionCodo(i,1))
       i=i+1;
       pause(0.3)
  
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.SCodo.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ResultadosButton = uibutton(app.GridLayout2, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.SimulacinRobotButton = uibutton(app.GridLayout2, 'push');
            app.SimulacinRobotButton.ButtonPushedFcn = createCallbackFcn(app, @SimulacinRobotButtonPushed, true);
            app.SimulacinRobotButton.Text = {'Simulación Robot'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout3, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.TiempoLabel = uilabel(app.GridLayout3);
            app.TiempoLabel.Text = {'Tiempo:'; ''};
            app.Transcurrido = uilabel(app.GridLayout3);
            app.Transcurrido.Text = {'0'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasTiempo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end