classdef Informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        CantidadTab              matlab.ui.container.Tab
        Prueba3Button            matlab.ui.control.Button
        Prueba2Button            matlab.ui.control.Button
        Prueba1Button            matlab.ui.control.Button
        VolverButton_3           matlab.ui.control.Button
        ActualizarDatosButton    matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        TiempoTab                matlab.ui.container.Tab
        VolverButton_2           matlab.ui.control.Button
        ActualizarDatosButton_2  matlab.ui.control.Button
        UITable_2                matlab.ui.control.Table
        CalidadTab               matlab.ui.container.Tab
        VolverButton             matlab.ui.control.Button
        ActualizarDatosButton_3  matlab.ui.control.Button
        UITable_3                matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ActualizarDatosButton
        function ActualizarDatosButtonPushed(app, event)
            datos  = readtable('DatosInformeCantidad.xlsx');
            app.UITable.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_2
        function ActualizarDatosButton_2Pushed(app, event)
            datos  = readtable('DatosInformeTiempo.xlsx');
            app.UITable_2.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_3
        function ActualizarDatosButton_3Pushed(app, event)
            datos  = readtable('DatosInformeCalidad.xlsx');
            app.UITable_3.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: VolverButton_3
        function VolverButton_3Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton_2
        function VolverButton_2Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: Prueba1Button
        function Prueba1ButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CantidadTab = uitab(app.TabGroup);
            app.CantidadTab.Title = 'Cantidad ';
            app.UITable = uitable(app.CantidadTab);
            app.UITable.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Número de Movimientos'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable.RowName = {};
            app.ActualizarDatosButton = uibutton(app.CantidadTab, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.VolverButton_3 = uibutton(app.CantidadTab, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_3Pushed, true);
            app.VolverButton_3.Text = 'Volver';
            app.Prueba1Button = uibutton(app.CantidadTab, 'push');
            app.Prueba1Button.ButtonPushedFcn = createCallbackFcn(app, @Prueba1ButtonPushed, true);
            app.Prueba1Button.Text = 'Prueba 1';
            app.Prueba2Button = uibutton(app.CantidadTab, 'push');
            app.Prueba2Button.Text = 'Prueba 2';
            app.Prueba3Button = uibutton(app.CantidadTab, 'push');
            app.Prueba3Button.Text = 'Prueba 3';
            app.TiempoTab = uitab(app.TabGroup);
            app.TiempoTab.Title = 'Tiempo ';
            app.UITable_2 = uitable(app.TiempoTab);
            app.UITable_2.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Tiempo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_2.RowName = {};
            app.ActualizarDatosButton_2 = uibutton(app.TiempoTab, 'push');
            app.ActualizarDatosButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_2Pushed, true);
            app.ActualizarDatosButton_2.Text = 'Actualizar Datos';
            app.VolverButton_2 = uibutton(app.TiempoTab, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.CalidadTab = uitab(app.TabGroup);
            app.CalidadTab.Title = 'Calidad';
            app.UITable_3 = uitable(app.CalidadTab);
            app.UITable_3.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Porcentaje de Fallo Total'; 'Porcentajes de Fallo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_3.RowName = {};
            app.ActualizarDatosButton_3 = uibutton(app.CalidadTab, 'push');
            app.ActualizarDatosButton_3.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_3Pushed, true);
            app.ActualizarDatosButton_3.Text = 'Actualizar Datos';
            app.VolverButton = uibutton(app.CalidadTab, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = {'Volver'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end