classdef Progreso < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        GridLayout2       matlab.ui.container.GridLayout
        GridLayout4       matlab.ui.container.GridLayout
        TiempoLabel_2     matlab.ui.control.Label
        FeedbackButton    matlab.ui.control.Button
        TiempoLabel       matlab.ui.control.Label
        GridLayout5       matlab.ui.container.GridLayout
        CalidadLabel_2    matlab.ui.control.Label
        FeedbackButton_2  matlab.ui.control.Button
        CalidadLabel      matlab.ui.control.Label
        GridLayout6       matlab.ui.container.GridLayout
        CantidadLabel_2   matlab.ui.control.Label
        FeedbackButton_3  matlab.ui.control.Button
        CantidadLabel     matlab.ui.control.Label
        GridLayout3       matlab.ui.container.GridLayout
        InicioButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%           Leemos el excel
%             
            [num ,x] = LeerExcel(app,'datosHA.xlsx')
            numT = num;
            t = x ;
            [num ,x] = LeerExcel(app,'ProgresoCalidad.xlsx')
            numCl = num;
            cl = x;
            [num ,x] = LeerExcel(app,'ProgresoCantidad.xlsx')
            numC = num;
            c = x;
           
            texto1 = 'La duracion del movimineto es de  ';
            %convertimos el int en un string para poder imprimirlo en el label
            value1 = texto1 + string(t) + ' segundos';          
            set(app.TiempoLabel_2, 'Text', value1);
            
            texto2 = 'la calidad es del  ';
            %convertimos el int en un string para poder imprimirlo en el label
            value2 = texto2 + string(cl) + ' %';
            set(app.CalidadLabel_2, 'Text', value2);
            
            texto3 = 'Se han realizado un total de  ';
            %convertimos el int en un string para poder imprimirlo en el label
            value3 = texto3 + string(c) + ' movimientos';
            set(app.CantidadLabel_2, 'Text', value3);
            
        end

        % Callback function
        function JugarButtonPushed(app, event)
            metricas;
            closereq ();
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            Inicio;
            closereq ();
        end

        % Button pushed function: FeedbackButton
        function FeedbackButtonPushed(app, event)
           %ventana con feedback tiempo
           [num ,x] = LeerExcel(app,'datosHA.xlsx')
           
           fig = uifigure; 
           
           textofeedback = 'hola, todo correcto';
          
            uialert(fig,textofeedback, 'Feedback tiempo','Icon','info');
        
        end

        % Button pushed function: FeedbackButton_2
        function FeedbackButton_2Pushed(app, event)
             %ventana con feedback calidad
            [num ,x] = LeerExcel(app,'ProgresoCalidad.xlsx')
            
           fig = uifigure; 
           
           textofeedback = 'hola, todo correcto';
          
            uialert(fig,textofeedback, 'Feedback calidad ','Icon','info');
        end

        % Button pushed function: FeedbackButton_3
        function FeedbackButton_3Pushed(app, event)
              %ventana con feedback cantidad
            [num ,x] = LeerExcel(app,'ProgresoCantidad.xlsx')
            
           fig = uifigure; 
           
           textofeedback = 'hola, todo correcto';
          
            uialert(fig,textofeedback, 'Feedback cantidad','Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.TiempoLabel_2 = uilabel(app.GridLayout4);
            app.TiempoLabel_2.Text = 'Tiempo: ';
            app.FeedbackButton = uibutton(app.GridLayout4, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.TiempoLabel = uilabel(app.GridLayout4);
            app.TiempoLabel.Text = 'Tiempo';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.CalidadLabel_2 = uilabel(app.GridLayout5);
            app.CalidadLabel_2.Text = 'Calidad:';
            app.FeedbackButton_2 = uibutton(app.GridLayout5, 'push');
            app.FeedbackButton_2.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButton_2Pushed, true);
            app.FeedbackButton_2.Text = 'Feedback';
            app.CalidadLabel = uilabel(app.GridLayout5);
            app.CalidadLabel.Text = 'Calidad';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.CantidadLabel_2 = uilabel(app.GridLayout6);
            app.CantidadLabel_2.Text = 'Cantidad: ';
            app.FeedbackButton_3 = uibutton(app.GridLayout6, 'push');
            app.FeedbackButton_3.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButton_3Pushed, true);
            app.FeedbackButton_3.Text = 'Feedback';
            app.CantidadLabel = uilabel(app.GridLayout6);
            app.CantidadLabel.Text = 'Cantidad';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.InicioButton = uibutton(app.GridLayout3, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Progreso

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end